classdef Resynthesizer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResynthesizerUIFigure          matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        HzLabel_3                      matlab.ui.control.Label
        dBLabel_3                      matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        dBLabel_2                      matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        MaximumpeakdeviationEditField  matlab.ui.control.EditField
        MaximumpeakdeviationEditFieldLabel  matlab.ui.control.Label
        TrajectorySettingsLabel        matlab.ui.control.Label
        InputfileLabel                 matlab.ui.control.Label
        LowestpermissibleamplitudeEditField  matlab.ui.control.EditField
        LowestpermissibleamplitudeEditFieldLabel  matlab.ui.control.Label
        HighestpermissiblefrequencyEditField  matlab.ui.control.EditField
        HighestpermissiblefrequencyEditFieldLabel  matlab.ui.control.Label
        LowestpermissiblefrequencyEditField  matlab.ui.control.EditField
        LowestpermissiblefrequencyEditFieldLabel  matlab.ui.control.Label
        MinimumpeakheightglobalEditField  matlab.ui.control.EditField
        MinimumpeakheightglobalEditFieldLabel  matlab.ui.control.Label
        MinimumpeakheightapproximationEditField  matlab.ui.control.EditField
        MinimumpeakheightapproximationEditFieldLabel  matlab.ui.control.Label
        FilteringSettingsLabel         matlab.ui.control.Label
        STFTSettingsLabel              matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.EditField
        BetaEditFieldLabel             matlab.ui.control.Label
        OverlaplengthEditField         matlab.ui.control.EditField
        OverlaplengthEditFieldLabel    matlab.ui.control.Label
        WindowlengthEditField          matlab.ui.control.EditField
        WindowlengthEditFieldLabel     matlab.ui.control.Label
        FFTlengthEditField             matlab.ui.control.EditField
        FFTlengthEditFieldLabel        matlab.ui.control.Label
        ChooseButton                   matlab.ui.control.Button
        ResynthesizeButton             matlab.ui.control.Button
        v01DominikCiesiokiewiczLabel   matlab.ui.control.Label
        ResynthesizerShowcaseLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Trajectories                   matlab.ui.control.UIAxes
        Input_Wavelet_Zoom             matlab.ui.control.UIAxes
        Output_Wavelet_Zoom            matlab.ui.control.UIAxes
        Output_STFT                    matlab.ui.control.UIAxes
        Output_Wavelet                 matlab.ui.control.UIAxes
        STFT                           matlab.ui.control.UIAxes
        Input_Wavelet                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        fftLength = 256;
        windowLength = 256;
        overlapLength = 192;
        beta = 6.0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.fftLength = 256;
            % app.windowLength = 256;
            % app.overlapLength = 192;
            % app.beta = 6.0;
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            [filetitle,path] = uigetfile('*.wav');

            [audioData, fs] = audioread(filetitle);
            auInfo = audioinfo(filetitle);

            % Plot original wavelet
            t = seconds(0:1/fs:(size(audioData,1)-1)/fs);
            plot(app.Input_Wavelet,t,audioData);

            % Plot zoomed wavelet
            plot(app.Input_Wavelet_Zoom,t(1:300),audioData(1:300));

            % Plot original STFT
            [s,f,t] = stft(audioData, fs, Window=kaiser(app.windowLength,app.beta), ...
                FFTLength=app.fftLength, OverlapLength=app.overlapLength, ...
                FrequencyRange="onesided");
            sdB = 20*log10(abs(s));
            imagesc(app.STFT, t, flipud(f), rot90(sdB'));
            set(app.STFT,'YDir', 'normal');
            ylim(app.STFT,[0 20000])
            colorbar(app.STFT)
        end

        % Button pushed function: ResynthesizeButton
        function ResynthesizeButtonPushed(app, event)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
        end

        % Value changed function: FFTlengthEditField
        function FFTlengthEditFieldValueChanged(app, event)
            value = app.FFTlengthEditField.Value;
            app.fftLength = value;
        end

        % Value changed function: WindowlengthEditField
        function WindowlengthEditFieldValueChanged(app, event)
            value = app.WindowlengthEditField.Value;
            app.windowLength = value;
        end

        % Value changed function: OverlaplengthEditField
        function OverlaplengthEditFieldValueChanged(app, event)
            value = app.OverlaplengthEditField.Value;
            app.overlapLength = value;
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            value = app.BetaEditField.Value;
            app.beta = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ResynthesizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1247, 1247};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {383, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResynthesizerUIFigure = uifigure('Visible', 'off');
            app.ResynthesizerUIFigure.Name = 'Resynthesizer';
            app.ResynthesizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ResynthesizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResynthesizerShowcaseLabel = uilabel(app.LeftPanel);
            app.ResynthesizerShowcaseLabel.Text = 'Resynthesizer Showcase';
            app.v01DominikCiesiokiewiczLabel = uilabel(app.LeftPanel);
            app.v01DominikCiesiokiewiczLabel.Text = 'v.0.1 Dominik Ciesiołkiewicz';
            app.ResynthesizeButton = uibutton(app.LeftPanel, 'push');
            app.ResynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResynthesizeButtonPushed, true);
            app.ResynthesizeButton.Text = 'Resynthesize';
            app.ChooseButton = uibutton(app.LeftPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.FFTlengthEditFieldLabel = uilabel(app.LeftPanel);
            app.FFTlengthEditFieldLabel.Text = 'FFT length';
            app.FFTlengthEditField = uieditfield(app.LeftPanel, 'text');
            app.FFTlengthEditField.ValueChangedFcn = createCallbackFcn(app, @FFTlengthEditFieldValueChanged, true);
            app.FFTlengthEditField.Value = '256';
            app.WindowlengthEditFieldLabel = uilabel(app.LeftPanel);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.WindowlengthEditField = uieditfield(app.LeftPanel, 'text');
            app.WindowlengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowlengthEditFieldValueChanged, true);
            app.WindowlengthEditField.Value = '256';
            app.OverlaplengthEditFieldLabel = uilabel(app.LeftPanel);
            app.OverlaplengthEditFieldLabel.Text = 'Overlap length';
            app.OverlaplengthEditField = uieditfield(app.LeftPanel, 'text');
            app.OverlaplengthEditField.ValueChangedFcn = createCallbackFcn(app, @OverlaplengthEditFieldValueChanged, true);
            app.OverlaplengthEditField.Value = '192';
            app.BetaEditFieldLabel = uilabel(app.LeftPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.LeftPanel, 'text');
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.BetaEditField.Value = '6.0';
            app.STFTSettingsLabel = uilabel(app.LeftPanel);
            app.STFTSettingsLabel.Text = 'STFT Settings';
            app.FilteringSettingsLabel = uilabel(app.LeftPanel);
            app.FilteringSettingsLabel.Text = 'Filtering Settings';
            app.MinimumpeakheightapproximationEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumpeakheightapproximationEditFieldLabel.Text = 'Minimum peak height - approximation';
            app.MinimumpeakheightapproximationEditField = uieditfield(app.LeftPanel, 'text');
            app.MinimumpeakheightapproximationEditField.Value = '10';
            app.MinimumpeakheightglobalEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumpeakheightglobalEditFieldLabel.Text = 'Minimum peak height - global';
            app.MinimumpeakheightglobalEditField = uieditfield(app.LeftPanel, 'text');
            app.MinimumpeakheightglobalEditField.Value = '-40';
            app.LowestpermissiblefrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.LowestpermissiblefrequencyEditFieldLabel.Text = 'Lowest permissible frequency';
            app.LowestpermissiblefrequencyEditField = uieditfield(app.LeftPanel, 'text');
            app.LowestpermissiblefrequencyEditField.Value = '20';
            app.HighestpermissiblefrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.HighestpermissiblefrequencyEditFieldLabel.Text = 'Highest permissible frequency';
            app.HighestpermissiblefrequencyEditField = uieditfield(app.LeftPanel, 'text');
            app.HighestpermissiblefrequencyEditField.Value = '16000';
            app.LowestpermissibleamplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.LowestpermissibleamplitudeEditFieldLabel.Text = 'Lowest permissible amplitude';
            app.LowestpermissibleamplitudeEditField = uieditfield(app.LeftPanel, 'text');
            app.LowestpermissibleamplitudeEditField.Value = '-70';
            app.InputfileLabel = uilabel(app.LeftPanel);
            app.InputfileLabel.Text = 'Input file';
            app.TrajectorySettingsLabel = uilabel(app.LeftPanel);
            app.TrajectorySettingsLabel.Text = 'Trajectory Settings';
            app.MaximumpeakdeviationEditFieldLabel = uilabel(app.LeftPanel);
            app.MaximumpeakdeviationEditFieldLabel.Text = 'Maximum peak deviation';
            app.MaximumpeakdeviationEditField = uieditfield(app.LeftPanel, 'text');
            app.MaximumpeakdeviationEditField.Value = '30';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.dBLabel = uilabel(app.LeftPanel);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.LeftPanel);
            app.dBLabel_2.Text = 'dB';
            app.HzLabel = uilabel(app.LeftPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.LeftPanel);
            app.HzLabel_2.Text = 'Hz';
            app.dBLabel_3 = uilabel(app.LeftPanel);
            app.dBLabel_3.Text = 'dB';
            app.HzLabel_3 = uilabel(app.LeftPanel);
            app.HzLabel_3.Text = 'Hz';
            app.RightPanel = uipanel(app.GridLayout);
            app.Input_Wavelet = uiaxes(app.RightPanel);
            title(app.Input_Wavelet, 'Input wavelet')
            xlabel(app.Input_Wavelet, 'Time (s)')
            ylabel(app.Input_Wavelet, 'Amplitude')
            zlabel(app.Input_Wavelet, 'Z')
            app.STFT = uiaxes(app.RightPanel);
            title(app.STFT, 'STFT')
            xlabel(app.STFT, 'Time (s)')
            ylabel(app.STFT, 'Frequency (kHz)')
            zlabel(app.STFT, 'Z')
            app.Output_Wavelet = uiaxes(app.RightPanel);
            title(app.Output_Wavelet, 'Output wavelet')
            xlabel(app.Output_Wavelet, 'Time (s)')
            ylabel(app.Output_Wavelet, 'Amplitude')
            zlabel(app.Output_Wavelet, 'Z')
            app.Output_STFT = uiaxes(app.RightPanel);
            title(app.Output_STFT, 'STFT of output wavelet')
            xlabel(app.Output_STFT, 'Time (s)')
            ylabel(app.Output_STFT, 'Frequency (kHz)')
            zlabel(app.Output_STFT, 'Z')
            app.Output_Wavelet_Zoom = uiaxes(app.RightPanel);
            title(app.Output_Wavelet_Zoom, 'Output wavelet (zoom)')
            xlabel(app.Output_Wavelet_Zoom, 'Time (s)')
            ylabel(app.Output_Wavelet_Zoom, 'Amplitude')
            zlabel(app.Output_Wavelet_Zoom, 'Z')
            app.Input_Wavelet_Zoom = uiaxes(app.RightPanel);
            title(app.Input_Wavelet_Zoom, 'Input wavelet (zoom)')
            xlabel(app.Input_Wavelet_Zoom, 'Time (s)')
            ylabel(app.Input_Wavelet_Zoom, 'Amplitude')
            zlabel(app.Input_Wavelet_Zoom, 'Z')
            app.Trajectories = uiaxes(app.RightPanel);
            title(app.Trajectories, 'Trajectories')
            xlabel(app.Trajectories, 'Time (s)')
            ylabel(app.Trajectories, 'Frequency (Hz)')
            zlabel(app.Trajectories, 'Z')
            % Show the figure after all components are created
            app.ResynthesizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Resynthesizer_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ResynthesizerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ResynthesizerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResynthesizerUIFigure)
        end
    end
end