classdef Resynthesizer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResynthesizerUIFigure          matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        UseFletcherMunson40dBCheckBox  matlab.ui.control.CheckBox
        MinimumpeakheightlocalEditField  matlab.ui.control.EditField
        MinimumpeakheightlocalEditFieldLabel  matlab.ui.control.Label
        dBLabel_4                      matlab.ui.control.Label
        HzLabel_3                      matlab.ui.control.Label
        dBLabel_3                      matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        dBLabel_2                      matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        MaximumpeakdeviationEditField  matlab.ui.control.EditField
        MaximumpeakdeviationEditFieldLabel  matlab.ui.control.Label
        TrajectorySettingsLabel        matlab.ui.control.Label
        InputfileLabel                 matlab.ui.control.Label
        LowestpermissibleamplitudeEditField  matlab.ui.control.EditField
        LowestpermissibleamplitudeEditFieldLabel  matlab.ui.control.Label
        HighestpermissiblefrequencyEditField  matlab.ui.control.EditField
        HighestpermissiblefrequencyEditFieldLabel  matlab.ui.control.Label
        LowestpermissiblefrequencyEditField  matlab.ui.control.EditField
        LowestpermissiblefrequencyEditFieldLabel  matlab.ui.control.Label
        MinimumpeakheightglobalEditField  matlab.ui.control.EditField
        MinimumpeakheightglobalEditFieldLabel  matlab.ui.control.Label
        MinimumpeakheightapproximationEditField  matlab.ui.control.EditField
        MinimumpeakheightapproximationEditFieldLabel  matlab.ui.control.Label
        FilteringSettingsLabel         matlab.ui.control.Label
        STFTSettingsLabel              matlab.ui.control.Label
        BetaEditField                  matlab.ui.control.EditField
        BetaEditFieldLabel             matlab.ui.control.Label
        OverlaplengthEditField         matlab.ui.control.EditField
        OverlaplengthEditFieldLabel    matlab.ui.control.Label
        WindowlengthEditField          matlab.ui.control.EditField
        WindowlengthEditFieldLabel     matlab.ui.control.Label
        FFTlengthEditField             matlab.ui.control.EditField
        FFTlengthEditFieldLabel        matlab.ui.control.Label
        ChooseButton                   matlab.ui.control.Button
        ResynthesizeButton             matlab.ui.control.Button
        DominikCiesiokiewiczLabel      matlab.ui.control.Label
        ResynthesizerShowcaseLabel     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Trajectories_Plot              matlab.ui.control.UIAxes
        Input_Waveform_Zoom            matlab.ui.control.UIAxes
        Output_Waveform_Zoom           matlab.ui.control.UIAxes
        Output_STFT                    matlab.ui.control.UIAxes
        Output_Waveform                matlab.ui.control.UIAxes
        STFT                           matlab.ui.control.UIAxes
        Input_Waveform                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        fftLength = 256;
        windowLength = 256;
        overlapLength = 192;
        beta = 6.0;
        maximumPeakDeviation = 30;
        minimumPeakHeightApprox = 10;
        minimumPeakHeightGlobal = -40;
        minimumPeakHeightLocal = -10;
        lowestPermissibleFrequency = 20;
        highestPermissibleFrequency = 16000;
        lowestPermissibleAmplitude = -70;
        normalize = false;

        audioData = [];
        fs = 0;
        magnitude = [];
        frequency = [];
        time = [];
        magnitudeDecibels = [];
        trajectories = [];
        output = [];
        t = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.SaveButton,'Enable','off')
            set(app.ResynthesizeButton,'Enable','off')
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            [filetitle,~] = uigetfile('*.wav');

            [app.audioData, app.fs] = audioread(filetitle);
            % auInfo = audioinfo(filetitle);

            % Plot original wavelet
            app.t = seconds(0:1/app.fs:(size(app.audioData,1)-1)/app.fs);
            plot(app.Input_Waveform,app.audioData);

            % Plot zoomed wavelet
            plot(app.Input_Waveform_Zoom,app.audioData(1:300));

            % Plot original STFT
            [s,f,app.t] = stft(app.audioData, app.fs, Window=kaiser(app.windowLength,app.beta), ...
                FFTLength=app.fftLength, OverlapLength=app.overlapLength, ...
                FrequencyRange="onesided");
            sdB = 20*log10(abs(s));
            imagesc(app.STFT, app.t, flipud(f), rot90(sdB'));
            set(app.STFT,'YDir', 'normal');
            ylim(app.STFT,[0 20000])
            colorbar(app.STFT)

            cla(app.Output_Waveform);
            cla(app.Output_Waveform_Zoom);
            cla(app.Output_STFT);
            cla(app.Trajectories_Plot);

            set(app.SaveButton,'Enable','off')
            set(app.ResynthesizeButton,'Enable','on')
        end

        % Button pushed function: ResynthesizeButton
        function ResynthesizeButtonPushed(app, event)
            hopsize = app.fftLength - app.overlapLength;
            
            prog_bar = uiprogressdlg(app.ResynthesizerUIFigure,'Title','Calculating...',...
                'Message','Calculating STFT...');

            [app.magnitude,app.frequency,app.time] = stft(app.audioData,app.fs, ...
                Window=kaiser(app.windowLength,app.beta), FFTLength=app.fftLength, ...
                OverlapLength=app.overlapLength, FrequencyRange="onesided");

            app.magnitudeDecibels = 20*log10(abs(app.magnitude));
            
            prog_bar.Value = .20; 
            prog_bar.Message = 'Finding prominent spectral peaks...';

            [~, frequencyPeaksFiltered] = step1_find_and_filter_prominent_spectral_peaks( ...
                app.magnitudeDecibels, app.frequency, app.minimumPeakHeightLocal, ...
                app.minimumPeakHeightGlobal, app.lowestPermissibleAmplitude, ...
                app.lowestPermissibleFrequency, app.highestPermissibleFrequency);

            fletcher_and_mundson_40dB = fletcher_and_mundson_40dB_curve_generator(app.frequency);

            prog_bar.Value = .40; 
            prog_bar.Message = 'Interpolating peaks...';

            if(app.normalize)
                peaks = step2_interpolation(frequencyPeaksFiltered, ...
                    app.magnitudeDecibels, app.frequency, fletcher_and_mundson_40dB);
                peaks = normalize_amplitudes(peaks, app.windowLength, app.beta);
            else
                peaks = step2_interpolation_no_fm40(frequencyPeaksFiltered, ...
                    app.magnitudeDecibels, app.frequency);
                peaks = normalize_amplitudes(peaks, app.windowLength, app.beta);
            end

            prog_bar.Value = .60; 
            prog_bar.Message = 'Assigning peak frequency trajectories...';

            app.trajectories = step3_assign_peak_frequency_trajectories(peaks, app.maximumPeakDeviation);
            audioDataLength = length(app.audioData);
            app.output = step4_resynthesize(app.trajectories, app.fs, hopsize, audioDataLength);

            prog_bar.Value = .80; 
            prog_bar.Message = 'Creating plots...';

            %%% Plots
            plot(app.Output_Waveform,app.output);
            % xlim(app.Output_Waveform,[0 length(app.output)])
            plot(app.Output_Waveform_Zoom,app.output(1:300));

            % PLOT TRAJECTORIES
            for i = 1:size(app.trajectories,2)
                x = [];
                y = [];
                for j = 1:4:size(app.trajectories,1)
                    if(isnan(app.trajectories(j+1,i)) || isnan(app.trajectories(j+3,i)) || (app.trajectories(j+1,i)==0 && app.trajectories(j+3,i)==0))
                        continue;
                    end
                    x = [x, app.trajectories(j+1,i)];
                    y = [y, app.trajectories(j+3,i)];
                end
                plot(app.Trajectories_Plot,x,y,'LineWidth',3);
                hold(app.Trajectories_Plot,'on');
            end
            hold(app.Trajectories_Plot,'off');
            xlim(app.Trajectories_Plot,[0 length(x)])
            ylim(app.Trajectories_Plot,[0 20000])

            % OUTPUT STFT
            [s,f,ti] = stft(app.output, app.fs, Window=kaiser(app.windowLength,app.beta), ...
                FFTLength=app.fftLength, OverlapLength=app.overlapLength, ...
                FrequencyRange="onesided");
            sdB = 20*log10(abs(s));
            imagesc(app.Output_STFT, ti, flipud(f), rot90(sdB'));
            set(app.Output_STFT,'YDir', 'normal');
            ylim(app.Output_STFT,[0 20000])
            colorbar(app.Output_STFT)

            prog_bar.Value = 1;
            prog_bar.Message = 'Finished';
            pause(.5);
            close(prog_bar);

            set(app.SaveButton,'Enable','on')
            set(app.ResynthesizeButton,'Enable','on')
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite("output.wav",app.output,app.fs);

            message = {'Resynthesized sound was saved as ''output.wav'' in the app directory.'};
            uialert(app.ResynthesizerUIFigure,message,'File saved',...
                'Icon','info');
        end

        % Value changed function: FFTlengthEditField
        function FFTlengthEditFieldValueChanged(app, event)
            app.fftLength = str2double(app.FFTlengthEditField.Value);
        end

        % Value changed function: WindowlengthEditField
        function WindowlengthEditFieldValueChanged(app, event)
            app.windowLength = str2double(app.WindowlengthEditField.Value);
        end

        % Value changed function: OverlaplengthEditField
        function OverlaplengthEditFieldValueChanged(app, event)
            app.overlapLength = str2double(app.OverlaplengthEditField.Value);
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            app.beta = str2double(app.BetaEditField.Value);
        end

        % Value changed function: MaximumpeakdeviationEditField
        function MaximumpeakdeviationEditFieldValueChanged(app, event)
            app.maximumPeakDeviation = str2double(app.MaximumpeakdeviationEditField.Value);
        end

        % Value changed function: MinimumpeakheightapproximationEditField
        function MinimumpeakheightapproximationEditFieldValueChanged(app, event)
            app.minimumPeakHeightApprox = str2double(app.MinimumpeakheightapproximationEditField.Value);
        end

        % Value changed function: MinimumpeakheightglobalEditField
        function MinimumpeakheightglobalEditFieldValueChanged(app, event)
            app.minimumPeakHeightGlobal = str2double(app.MinimumpeakheightglobalEditField.Value);
        end

        % Value changed function: LowestpermissiblefrequencyEditField
        function LowestpermissiblefrequencyEditFieldValueChanged(app, event)
            app.lowestPermissibleFrequency = str2double(app.LowestpermissiblefrequencyEditField.Value);
        end

        % Value changed function: HighestpermissiblefrequencyEditField
        function HighestpermissiblefrequencyEditFieldValueChanged(app, event)
            app.highestPermissibleFrequency = str2double(app.HighestpermissiblefrequencyEditField.Value);
        end

        % Value changed function: LowestpermissibleamplitudeEditField
        function LowestpermissibleamplitudeEditFieldValueChanged(app, event)
            app.lowestPermissibleAmplitude = str2double(app.LowestpermissibleamplitudeEditField.Value);
        end

        % Value changed function: MinimumpeakheightlocalEditField
        function MinimumpeakheightlocalEditFieldValueChanged(app, event)
            app.minimumPeakHeightLocal = str2double(app.MinimumpeakheightlocalEditField.Value);
        end

        % Callback function
        function TestButtonPushed(app, event)
            % myFunction(app.TestButton)
            % 
            % prog_bar = uiprogressdlg(app.ResynthesizerUIFigure,'Title','Calculating...',...
            %     'Message','Opening the application');
            % pause(.5)
            % prog_bar.Value = .33; 
            % prog_bar.Message = 'Loading your data';
            % pause(1)
            % 
            % % Perform calculations
            % % ...
            % prog_bar.Value = .67;
            % prog_bar.Message = 'Processing the data';
            % pause(1)
            % 
            % % Finish calculations
            % % ...
            % prog_bar.Value = 1;
            % prog_bar.Message = 'Finishing';
            % pause(1)
            % 
            % % Close dialog box
            % close(prog_bar)
        end

        % Value changed function: UseFletcherMunson40dBCheckBox
        function UseFletcherMunson40dBCheckBoxValueChanged(app, event)
            value = app.UseFletcherMunson40dBCheckBox.Value;
            if(~value)
                app.normalize = false;
            else
                app.normalize = true;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ResynthesizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1247, 1247};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {383, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResynthesizerUIFigure = uifigure('Visible', 'off');
            app.ResynthesizerUIFigure.Name = 'Resynthesizer';
            app.ResynthesizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ResynthesizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResynthesizerShowcaseLabel = uilabel(app.LeftPanel);
            app.ResynthesizerShowcaseLabel.Text = 'Resynthesizer Showcase';
            app.DominikCiesiokiewiczLabel = uilabel(app.LeftPanel);
            app.DominikCiesiokiewiczLabel.Text = '06.09.2023 Dominik Ciesiołkiewicz';
            app.ResynthesizeButton = uibutton(app.LeftPanel, 'push');
            app.ResynthesizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResynthesizeButtonPushed, true);
            app.ResynthesizeButton.Text = 'Resynthesize';
            app.ChooseButton = uibutton(app.LeftPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.FFTlengthEditFieldLabel = uilabel(app.LeftPanel);
            app.FFTlengthEditFieldLabel.Text = 'FFT length';
            app.FFTlengthEditField = uieditfield(app.LeftPanel, 'text');
            app.FFTlengthEditField.ValueChangedFcn = createCallbackFcn(app, @FFTlengthEditFieldValueChanged, true);
            app.FFTlengthEditField.Value = '256';
            app.WindowlengthEditFieldLabel = uilabel(app.LeftPanel);
            app.WindowlengthEditFieldLabel.Text = 'Window length';
            app.WindowlengthEditField = uieditfield(app.LeftPanel, 'text');
            app.WindowlengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowlengthEditFieldValueChanged, true);
            app.WindowlengthEditField.Value = '256';
            app.OverlaplengthEditFieldLabel = uilabel(app.LeftPanel);
            app.OverlaplengthEditFieldLabel.Text = 'Overlap length';
            app.OverlaplengthEditField = uieditfield(app.LeftPanel, 'text');
            app.OverlaplengthEditField.ValueChangedFcn = createCallbackFcn(app, @OverlaplengthEditFieldValueChanged, true);
            app.OverlaplengthEditField.Value = '192';
            app.BetaEditFieldLabel = uilabel(app.LeftPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.LeftPanel, 'text');
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.BetaEditField.Value = '6.0';
            app.STFTSettingsLabel = uilabel(app.LeftPanel);
            app.STFTSettingsLabel.Text = 'STFT Settings';
            app.FilteringSettingsLabel = uilabel(app.LeftPanel);
            app.FilteringSettingsLabel.Text = 'Filtering Settings';
            app.MinimumpeakheightapproximationEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumpeakheightapproximationEditFieldLabel.Text = 'Minimum peak height - approximation';
            app.MinimumpeakheightapproximationEditField = uieditfield(app.LeftPanel, 'text');
            app.MinimumpeakheightapproximationEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpeakheightapproximationEditFieldValueChanged, true);
            app.MinimumpeakheightapproximationEditField.Value = '10';
            app.MinimumpeakheightglobalEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumpeakheightglobalEditFieldLabel.Text = 'Minimum peak height - global';
            app.MinimumpeakheightglobalEditField = uieditfield(app.LeftPanel, 'text');
            app.MinimumpeakheightglobalEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpeakheightglobalEditFieldValueChanged, true);
            app.MinimumpeakheightglobalEditField.Value = '-40';
            app.LowestpermissiblefrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.LowestpermissiblefrequencyEditFieldLabel.Text = 'Lowest permissible frequency';
            app.LowestpermissiblefrequencyEditField = uieditfield(app.LeftPanel, 'text');
            app.LowestpermissiblefrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @LowestpermissiblefrequencyEditFieldValueChanged, true);
            app.LowestpermissiblefrequencyEditField.Value = '20';
            app.HighestpermissiblefrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.HighestpermissiblefrequencyEditFieldLabel.Text = 'Highest permissible frequency';
            app.HighestpermissiblefrequencyEditField = uieditfield(app.LeftPanel, 'text');
            app.HighestpermissiblefrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @HighestpermissiblefrequencyEditFieldValueChanged, true);
            app.HighestpermissiblefrequencyEditField.Value = '16000';
            app.LowestpermissibleamplitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.LowestpermissibleamplitudeEditFieldLabel.Text = 'Lowest permissible amplitude';
            app.LowestpermissibleamplitudeEditField = uieditfield(app.LeftPanel, 'text');
            app.LowestpermissibleamplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LowestpermissibleamplitudeEditFieldValueChanged, true);
            app.LowestpermissibleamplitudeEditField.Value = '-70';
            app.InputfileLabel = uilabel(app.LeftPanel);
            app.InputfileLabel.Text = 'Input file';
            app.TrajectorySettingsLabel = uilabel(app.LeftPanel);
            app.TrajectorySettingsLabel.Text = 'Trajectory Settings';
            app.MaximumpeakdeviationEditFieldLabel = uilabel(app.LeftPanel);
            app.MaximumpeakdeviationEditFieldLabel.Text = 'Maximum peak deviation';
            app.MaximumpeakdeviationEditField = uieditfield(app.LeftPanel, 'text');
            app.MaximumpeakdeviationEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumpeakdeviationEditFieldValueChanged, true);
            app.MaximumpeakdeviationEditField.Value = '30';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.dBLabel = uilabel(app.LeftPanel);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.LeftPanel);
            app.dBLabel_2.Text = 'dB';
            app.HzLabel = uilabel(app.LeftPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.LeftPanel);
            app.HzLabel_2.Text = 'Hz';
            app.dBLabel_3 = uilabel(app.LeftPanel);
            app.dBLabel_3.Text = 'dB';
            app.HzLabel_3 = uilabel(app.LeftPanel);
            app.HzLabel_3.Text = 'Hz';
            app.dBLabel_4 = uilabel(app.LeftPanel);
            app.dBLabel_4.Text = 'dB';
            app.MinimumpeakheightlocalEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumpeakheightlocalEditFieldLabel.Text = 'Minimum peak height - local';
            app.MinimumpeakheightlocalEditField = uieditfield(app.LeftPanel, 'text');
            app.MinimumpeakheightlocalEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumpeakheightlocalEditFieldValueChanged, true);
            app.MinimumpeakheightlocalEditField.Value = '-10';
            app.UseFletcherMunson40dBCheckBox = uicheckbox(app.LeftPanel);
            app.UseFletcherMunson40dBCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFletcherMunson40dBCheckBoxValueChanged, true);
            app.UseFletcherMunson40dBCheckBox.Text = 'Use volume normalization using FM 40dB curve';
            app.RightPanel = uipanel(app.GridLayout);
            app.Input_Waveform = uiaxes(app.RightPanel);
            title(app.Input_Waveform, 'Input waveform')
            xlabel(app.Input_Waveform, 'Time (samples)')
            ylabel(app.Input_Waveform, 'Amplitude')
            zlabel(app.Input_Waveform, 'Z')
            app.Input_Waveform.YLim = [-1 1];
            app.STFT = uiaxes(app.RightPanel);
            title(app.STFT, 'STFT')
            xlabel(app.STFT, 'Time (s)')
            ylabel(app.STFT, 'Frequency (kHz)')
            zlabel(app.STFT, 'Z')
            app.Output_Waveform = uiaxes(app.RightPanel);
            title(app.Output_Waveform, 'Output waveform')
            xlabel(app.Output_Waveform, 'Time (samples)')
            ylabel(app.Output_Waveform, 'Amplitude')
            zlabel(app.Output_Waveform, 'Z')
            app.Output_Waveform.YLim = [-1 1];
            app.Output_STFT = uiaxes(app.RightPanel);
            title(app.Output_STFT, 'STFT of output waveform')
            xlabel(app.Output_STFT, 'Time (s)')
            ylabel(app.Output_STFT, 'Frequency (kHz)')
            zlabel(app.Output_STFT, 'Z')
            app.Output_Waveform_Zoom = uiaxes(app.RightPanel);
            title(app.Output_Waveform_Zoom, 'Output waveform (zoom)')
            xlabel(app.Output_Waveform_Zoom, 'Time (samples)')
            ylabel(app.Output_Waveform_Zoom, 'Amplitude')
            zlabel(app.Output_Waveform_Zoom, 'Z')
            app.Output_Waveform_Zoom.YLim = [-1 1];
            app.Input_Waveform_Zoom = uiaxes(app.RightPanel);
            title(app.Input_Waveform_Zoom, 'Input waveform (zoom)')
            xlabel(app.Input_Waveform_Zoom, 'Time (samples)')
            ylabel(app.Input_Waveform_Zoom, 'Amplitude')
            zlabel(app.Input_Waveform_Zoom, 'Z')
            app.Input_Waveform_Zoom.YLim = [-1 1];
            app.Trajectories_Plot = uiaxes(app.RightPanel);
            title(app.Trajectories_Plot, 'Trajectories')
            xlabel(app.Trajectories_Plot, 'Time (trajectory no.)')
            ylabel(app.Trajectories_Plot, 'Frequency (Hz)')
            zlabel(app.Trajectories_Plot, 'Z')
            % Show the figure after all components are created
            app.ResynthesizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Resynthesizer_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ResynthesizerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ResynthesizerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResynthesizerUIFigure)
        end
    end
end